classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        recorder          matlab.ui.control.StateButton
        Label_3           matlab.ui.control.Label
        Gauge             matlab.ui.control.NinetyDegreeGauge
        IPLabel           matlab.ui.control.Label
        IPEditField       matlab.ui.control.EditField
        EditFieldLabel    matlab.ui.control.Label
        portEditField     matlab.ui.control.NumericEditField
        dAEditFieldLabel  matlab.ui.control.Label
        dAEditField       matlab.ui.control.NumericEditField
        dBEditFieldLabel  matlab.ui.control.Label
        dBEditField       matlab.ui.control.NumericEditField
        mLabel            matlab.ui.control.Label
        mLabel_2          matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        distEditField     matlab.ui.control.EditField
        beepbeepAbtn      matlab.ui.control.StateButton
        beepbeepBbtn      matlab.ui.control.StateButton
        mode1LampLabel    matlab.ui.control.Label
        mode1Lamp         matlab.ui.control.Lamp
        mode2LampLabel    matlab.ui.control.Label
        mode2Lamp         matlab.ui.control.Lamp
        mode3LampLabel    matlab.ui.control.Label
        mode3Lamp         matlab.ui.control.Lamp
        RightPanel        matlab.ui.container.Panel
        Label             matlab.ui.control.Label
        recvInfo          matlab.ui.control.TextArea
        Label_2           matlab.ui.control.Label
        sendInfo          matlab.ui.control.TextArea
        sendBtn           matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        myRecorder  audiorecorder; % 录音
        t timer; % 录音计时器
        beepbeep_mode = 0; % beepbeep的传输内容
        beepbeep_type = 'A'; % beepbeep是设备A或B
        preamble_bit_num = 20; % 前导码长度
        preamble_code; % 前导码
        mode = 0; % FSK接受的阶段
        tA1 = 0;
        tA3 = 0;
        tB1 = 0;
        tB3 = 0;
        
        chirp_signal = chirp(0:1/48000:0.5, 3000, 0.5, 5000);
        chirp_f0 = 3000;
        chirp_f1 = 5000;
        chirp_duration = 0.5;
        
        
        pStart = 0; % 当前包的开头点
        
        f0 = 4000; % 低频率
        f1 = 6000; % 高频率
        duration = 0.025; % 间隔
        Fs = 48000; % 采样率
        
        data_recv = -1; % tcp server 收到的信息
        ip; % tcp传输的ip地址
        port; % tcp传输的目标端口
        
        dA = 0; % 设备A的麦克风与扬声器的距离
        dB = 0; % 设备B的麦克风与扬声器的距离
        
    end
    
    properties (Access = private)

    end
    
    methods (Access = private)
        
        function recorderTimerFcn(app)
            myRecording = getaudiodata(app.myRecorder);
            plot(app.UIAxes, myRecording(end-100:end));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: recorder
        function recorderValueChanged(app, event)
            
            value = app.recorder.Value;
            if  value == 1
                app.t= timer('Period',0.1,'ExecutionMode',"fixedRate");
                app.myRecorder = audiorecorder(app.Fs, 24, 1);
                record(app.myRecorder);
                app.t.StartDelay = 0.6;
                app.t.Period = 0.1;
                app.t.TimerFcn=@(~,~)recorderTF(app);
                start(app.t);
                
            elseif  value == 0  
                stop(app.t);
                delete(app.t);
                pause(app.myRecorder);
                play(app.myRecorder);
            end
        end

        % Value changed function: beepbeepAbtn
        function beepbeepAbtnValueChanged(app, event)
            value = app.beepbeepAbtn.Value;
            
            if value == 1
                app.beepbeep_type = 'A';
                app.beepbeep_mode = 1;
                app.ip = app.IPEditField.Value;
                app.port = app.portEditField.Value;
                app.dA = app.dAEditField.Value;
                app.dB = app.dBEditField.Value;
            
                tcp_server(app);
            
                app.t = timer('Period', 0.1, 'ExecutionMode', 'fixedRate');
                app.myRecorder = audiorecorder(app.Fs,24,1);
                record(app.myRecorder);
                app.t.StartDelay = 0.7;
                app.t.TimerFcn = @(~,~)beepbeepTF(app);
                start(app.t);
                
                chirp_t = timer("StartDelay",0.5, 'ExecutionMode');
                chirp_t.TimerFcn = @(~,~)play_chirp;
                start(chirp_t);
                
            elseif value == 0
                stop(app.t);
                delete(app.t);
                pause(app.myRecorder);
                
            end
        end

        % Value changed function: beepbeepBbtn
        function beepbeepBbtnValueChanged(app, event)
            value = app.beepbeepBbtn.Value;
            if value == 1
                app.beepbeep_type = 'B';
                app.beepbeep_mode = 1;
                app.mode1Lamp.Color = [0 1 0];
                app.ip = app.IPEditField.Value;
                app.port = app.portEditField.Value;
                app.dA = app.dAEditField.Value;
                app.dB = app.dBEditField.Value;
            
                app.t = timer('Period', 0.1, 'ExecutionMode', 'fixedRate');
                app.myRecorder = audiorecorder(app.Fs,24,1);
                record(app.myRecorder);
                app.t.StartDelay = 0.7;
                app.t.TimerFcn = @(~,~)beepbeepTF(app);
                start(app.t);
                
            elseif value == 0
                stop(app.t);
                delete(app.t);
                pause(app.myRecorder);
                
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {517, 517};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {285, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.recorder = uibutton(app.LeftPanel, 'state');
            app.recorder.ValueChangedFcn = createCallbackFcn(app, @recorderValueChanged, true);
            app.recorder.Text = '接受信号';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '信号强度';
            app.Gauge = uigauge(app.LeftPanel, 'ninetydegree');
            app.IPLabel = uilabel(app.LeftPanel);
            app.IPLabel.Text = 'IP地址';
            app.IPEditField = uieditfield(app.LeftPanel, 'text');
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Text = '端口';
            app.portEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dAEditFieldLabel = uilabel(app.LeftPanel);
            app.dAEditFieldLabel.Text = 'dA';
            app.dAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dBEditFieldLabel = uilabel(app.LeftPanel);
            app.dBEditFieldLabel.Text = 'dB';
            app.dBEditField = uieditfield(app.LeftPanel, 'numeric');
            app.mLabel = uilabel(app.LeftPanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.LeftPanel);
            app.mLabel_2.Text = 'm';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '距离为';
            app.distEditField = uieditfield(app.LeftPanel, 'text');
            app.beepbeepAbtn = uibutton(app.LeftPanel, 'state');
            app.beepbeepAbtn.ValueChangedFcn = createCallbackFcn(app, @beepbeepAbtnValueChanged, true);
            app.beepbeepAbtn.Text = '测距A';
            app.beepbeepBbtn = uibutton(app.LeftPanel, 'state');
            app.beepbeepBbtn.ValueChangedFcn = createCallbackFcn(app, @beepbeepBbtnValueChanged, true);
            app.beepbeepBbtn.Text = '测距B';
            app.mode1LampLabel = uilabel(app.LeftPanel);
            app.mode1LampLabel.Text = 'mode1';
            app.mode1Lamp = uilamp(app.LeftPanel);
            app.mode2LampLabel = uilabel(app.LeftPanel);
            app.mode2LampLabel.Text = 'mode2';
            app.mode2Lamp = uilamp(app.LeftPanel);
            app.mode3LampLabel = uilabel(app.LeftPanel);
            app.mode3LampLabel.Text = 'mode3';
            app.mode3Lamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '接收信号';
            app.recvInfo = uitextarea(app.RightPanel);
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '发送信号';
            app.sendInfo = uitextarea(app.RightPanel);
            app.sendBtn = uibutton(app.RightPanel, 'push');
            app.sendBtn.Text = '发送';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end